classdef DatasetUploadApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NDIDatasetWizardUIFigure        matlab.ui.Figure
        RemoveAffiliationButton         matlab.ui.control.Button
        AddAffiliationButton            matlab.ui.control.Button
        FooterPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        MainGridLayout                  matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        GridLayout_Step0                matlab.ui.container.GridLayout
        GridLayout_Step0_C2             matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        WelcomeLabel                    matlab.ui.control.Label
        GridLayout_Step0_C3             matlab.ui.container.GridLayout
        GetStartedButton                matlab.ui.control.Button
        DatasetOverviewTab              matlab.ui.container.Tab
        GridLayout_Step1                matlab.ui.container.GridLayout
        DatasetInformationLabel         matlab.ui.control.Label
        DatasetInformationPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        CommentsDetailsTextArea         matlab.ui.control.TextArea
        CommentsDetailsLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DatasetShortNameLabel           matlab.ui.control.Label
        DatasetBranchTitleLabel         matlab.ui.control.Label
        DatasetBranchTitleEditField     matlab.ui.control.EditField
        ShortNameEditField              matlab.ui.control.EditField
        Panel_4                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        AbstractLabel                   matlab.ui.control.Label
        AbstractTextArea                matlab.ui.control.TextArea
        AuthorsTab                      matlab.ui.container.Tab
        GridLayout_Step2                matlab.ui.container.GridLayout
        AuthorDetailsPanel              matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        AdditionalDetailsTextArea       matlab.ui.control.TextArea
        AdditionalDetailsTextAreaLabel  matlab.ui.control.Label
        AuthorEmailEditFieldLabel       matlab.ui.control.Label
        AuthorEmailEditField            matlab.ui.control.EditField
        GridLayout10                    matlab.ui.container.GridLayout
        SearchOrcidButton               matlab.ui.control.Button
        DigitalIdentifierORCIDEditField  matlab.ui.control.EditField
        DigitalIdentifierORCIDLabel     matlab.ui.control.Label
        FamilyNameEditField             matlab.ui.control.EditField
        FamilyNameEditFieldLabel        matlab.ui.control.Label
        GivenNameEditField              matlab.ui.control.EditField
        GivenNameEditFieldLabel         matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        AuthorListBox                   matlab.ui.control.ListBox
        GridLayout9                     matlab.ui.container.GridLayout
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        RemoveAuthorButton              matlab.ui.control.Button
        AddAuthorButton                 matlab.ui.control.Button
        CreateauthorsandfillouttheirdetailsLabel  matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout9_2                   matlab.ui.container.GridLayout
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        RemoveAuthorButton_2            matlab.ui.control.Button
        AddAuthorButton_2               matlab.ui.control.Button
        AffiliationTable                matlab.ui.control.Table
        InstitutesAffiliationsListBoxLabel  matlab.ui.control.Label
        AuthorRoleTree                  matlab.ui.container.CheckBoxTree
        CorrespondingNode               matlab.ui.container.TreeNode
        CustodianNode                   matlab.ui.container.TreeNode
        stAuthorNode                    matlab.ui.container.TreeNode
        AuthorRoleLabel                 matlab.ui.control.Label
        AuthorDetailsLabel              matlab.ui.control.Label
        DatasetDetailsTab               matlab.ui.container.Tab
        FundingEditField                matlab.ui.control.EditField
        FundingEditFieldLabel           matlab.ui.control.Label
        ExperimentalApproachLabel       matlab.ui.control.Label
        ExperimentalApproachTree        matlab.ui.container.CheckBoxTree
        TechniquesEmployedLabel_2       matlab.ui.control.Label
        TechniquesEmployedTree          matlab.ui.container.CheckBoxTree
        DataTypeTree                    matlab.ui.container.CheckBoxTree
        RawNode                         matlab.ui.container.TreeNode
        DerivedNode                     matlab.ui.container.TreeNode
        SimulatedNode                   matlab.ui.container.TreeNode
        VersionInnovationEditField      matlab.ui.control.EditField
        VersionInnovationEditFieldLabel  matlab.ui.control.Label
        AddRelatedPublicationButton     matlab.ui.control.Button
        LicenseDropDown                 matlab.ui.control.DropDown
        LicenseDropDownLabel            matlab.ui.control.Label
        FullDocumentationEditField      matlab.ui.control.EditField
        FullDocumentationEditFieldLabel  matlab.ui.control.Label
        RelatedPublicationsLabel        matlab.ui.control.Label
        RelatedPublicationUITable       matlab.ui.control.Table
        VersionIdentifierEditField      matlab.ui.control.EditField
        VersionIdentifierEditFieldLabel  matlab.ui.control.Label
        DataTypeLabel                   matlab.ui.control.Label
        ReleaseDateDatePicker           matlab.ui.control.DatePicker
        ReleaseDateDatePickerLabel      matlab.ui.control.Label
        DATASETDETAILSLabel             matlab.ui.control.Label
        ExperimentDetailsTab            matlab.ui.container.Tab
        SubjectInfoTab                  matlab.ui.container.Tab
        StrainListBox                   matlab.ui.control.ListBox
        BiologicalSexListBox            matlab.ui.control.ListBox
        AddSpeciesButton                matlab.ui.control.Button
        SpeciesEditField                matlab.ui.control.EditField
        UITableSubject                  matlab.ui.control.Table
        StrainClearButton               matlab.ui.control.Button
        SpeciesClearButton              matlab.ui.control.Button
        BiologicalSexClearButton        matlab.ui.control.Button
        StrainLabel_3                   matlab.ui.control.Label
        SpeciesLabel_2                  matlab.ui.control.Label
        BiologicalSexLabel              matlab.ui.control.Label
        AssignStrainButton              matlab.ui.control.Button
        SpeciesListBox                  matlab.ui.control.ListBox
        AssignSpeciesButton             matlab.ui.control.Button
        AssignBiologicalSexButton       matlab.ui.control.Button
        ProbeInfoTab                    matlab.ui.container.Tab
        UITableProbe                    matlab.ui.control.Table
        SubmitTab                       matlab.ui.container.Tab
        GridLayout_Step0_2              matlab.ui.container.GridLayout
        GridLayout_Step0_C2_2           matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        ErrorTextArea                   matlab.ui.control.TextArea
        ErrorTextAreaLabel              matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        WelcomeLabel_2                  matlab.ui.control.Label
        GridLayout_Step0_C3_2           matlab.ui.container.GridLayout
        SubmitButton                    matlab.ui.control.Button
        SaveChangesButton               matlab.ui.control.Button
        FooterpanelLabel                matlab.ui.control.Label
        DatasetIdentifierEditField      matlab.ui.control.EditField
        DatasetIdentifierEditFieldLabel  matlab.ui.control.Label
        FundingListBox                  matlab.ui.control.ListBox
        FundingListBoxLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Struct holding all the information entered in the app for saving
        % and loading
        DatasetInformation (1,1) struct
        
        % A struct for storing input form apps, like the Author form. Used
        % to keep external forms/figures in memory, but hidden
        UIForm (1,1) struct
        
        DatasetData (1,1) ndi.database.metadata_app.class.DatasetData
        DatasetVersionData (1,1) ndi.database.metadata_app.class.DatasetVersionData
        
        %Step 1
        DatasetBranchTitle
        DatasetShortName
        Abstract
        Comments
        
        %Step 2
        AuthorData (1,1) ndi.database.metadata_app.class.AuthorData
        
        %Step 3
        FullDocumentation
        RelatedPublication
        VersionIdentifier
        DataType
        VersionInnovation
        TechniquesEmployed
        ReleaseDate
        ExperimentalApproach
        License
        Funding

        %Step 4
        SpeciesData (1,1) ndi.database.metadata_app.class.SpeciesData
        SubjectData (1,1) ndi.database.metadata_app.class.SubjectData

        %Step 5
        ProbeData (1,1) ndi.database.metadata_app.class.ProbeData


        % A ndi session
        Session
        
        % A filepath for saving and retrieving the metadata information. It
        % is under Session/.ndi/NDIDatasetUpload
        FilePath

    end

    properties (Access = private)
        Timer
    end
    
    methods (Access = private)

        function saveDatasetInformation(app, fieldName)
            
            if nargin < 2; fieldName = ''; end

            switch fieldName
                case 'Dataset'
                    app.DatasetInformation.Dataset = app.DatasetData.DatasetInfo;
                case 'DatasetVersionData'
                    app.DatasetInformation.DatasetVersion = app.DatasetVersionData.DatasetVersionInfo;

                %Step 1
                case 'DatasetBranchTitle'
                    app.DatasetInformation.DatasetBranchTitle = app.DatasetBranchTitle;
                    app.DatasetData.updateProperty("FullName", app.DatasetBranchTitle);
                    app.DatasetVersionData.updateProperty("FullName", app.DatasetBranchTitle);
                    app.saveDatasetInformation('Dataset');
                    app.saveDatasetInformation('DatasetVersionData');
                case 'DatasetShortName'
                    app.DatasetInformation.DatasetShortName = app.DatasetShortName;
                    app.DatasetData.updateProperty("ShortName", app.DatasetShortName);
                    app.DatasetVersionData.updateProperty("ShortName", app.DatasetShortName);
                    app.saveDatasetInformation('Dataset');
                    app.saveDatasetInformation('DatasetVersionData');
                case 'Abstract'
                    app.DatasetInformation.Abstract = app.Abstract;
                    app.DatasetData.updateProperty("Description", app.Abstract);
                    app.saveDatasetInformation('Dataset');
                case 'Comments'
                    app.DatasetInformation.Comments = app.Comments;
                    
                %Step 2
                case 'Author'
                    app.DatasetInformation.Author = app.AuthorData.AuthorList;
                    app.saveDatasetInformation('Dataset');
                    app.saveDatasetInformation('DatasetVersionData');
                %Step 3
                case 'FullDocumentation'
                    app.DatasetInformation.FullDocumentation = app.FullDocumentation;
                    app.DatasetVersionData.updateProperty("FullDocumentation", app.FullDocumentation);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'RelatedPublication'
                    app.DatasetInformation.RelatedPublication = app.RelatedPublication;
                    app.DatasetVersionData.updateProperty("RelatedPublication", app.RelatedPublication);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'VersionIdentifier'
                    app.DatasetInformation.VersionIdentifier = app.VersionIdentifier;
                    app.DatasetVersionData.updateProperty("VersionIdentifier", app.VersionIdentifier);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'DataType'
                    app.DatasetInformation.DataType = app.DataType;
                    app.DatasetVersionData.updateProperty("DataType", app.DataType);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'VersionInnovation'
                    app.DatasetInformation.VersionInnovation = app.VersionInnovation;
                    app.DatasetVersionData.updateProperty("VersionInnovation", app.VersionInnovation);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'TechniquesEmployed'
                    app.DatasetInformation.TechniquesEmployed = app.TechniquesEmployed;
                    app.DatasetVersionData.updateProperty("TechniquesEmployed", app.TechniquesEmployed);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'ReleaseDate'
                    app.DatasetInformation.ReleaseDate = app.ReleaseDate;
                    app.DatasetVersionData.updateProperty("ReleaseDate", app.ReleaseDate);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'ExperimentalApproach'
                    app.DatasetInformation.ExperimentalApproach = app.ExperimentalApproach;
                    app.DatasetVersionData.updateProperty("ExperimentalApproach", app.ExperimentalApproach);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'License'
                    app.DatasetInformation.License = app.License;
                    app.DatasetVersionData.updateProperty("License", app.License);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'Funding'
                    app.DatasetInformation.Funding = app.Funding;
                    app.DatasetVersionData.updateProperty("Funding", app.Funding);
                    app.saveDatasetInformation('DatasetVersionData');

                %Step 4
                case 'Species'
                    app.DatasetInformation.Species = app.SpeciesData.SpeciesList;
                case 'Subject'
                    app.DatasetInformation.Subjects = app.SubjectData.SubjectList;

                %Step 5
                case 'Probe'
                    app.DatasetInformation.Probe = app.ProbeData.ProbeList;
                
            end
            
            savePath = app.getDatasetFilepath();
            datasetInformation = app.DatasetInformation;
            disp(datasetInformation);
            save(savePath, "datasetInformation")
        end

        % function loadDatasetInformation(app)
        %     loadPath = app.getDatasetFilepath();
        %     if isfile(loadPath)
        %         S = load(loadPath, "datasetInformation");
        %         app.DatasetInformation = S.datasetInformation;
        % 
        %         if isfield(app.DatasetInformation, 'Author')
        %             if numel(app.DatasetInformation.Author) > 0
        %                 S = app.DatasetInformation.Author(1);
        %                 app.fillAuthorInputFieldsFromStruct(S)
        %                 app.AuthorData.AuthorList = app.DatasetInformation.Author;
        %                 app.updateAuthorListbox()
        %             end
        %         end
        %     end
        % end

        % Get the index for the selected author in the author listbox
        function authorIndex = getSelectedAuthorIndex(app)
            isSelected = strcmp(app.AuthorListBox.Items, app.AuthorListBox.Value);
            authorIndex = find(isSelected);
        end

        % Validate person property against openMINDS
        function updatePerson(app, propertyName, propertyValue)
        % Try to create an openMINDS Person with given property value and
        %show error if the the information is not valid. Note: requires
        %openMINDS_MATLAB to be on path.
            try
                p = openminds.core.Person(propertyName, propertyValue);
            catch ME
                uialert(app.NDIDatasetWizardUIFigure, ME.message, 'Invalid input')
            end
        end
      
        % Update author input fields based on a struct of author details
        function fillAuthorInputFieldsFromStruct(app, S)
            % Note: Not all fields are handled yet
            app.GivenNameEditField.Value = S.GivenName;
            app.FamilyNameEditField.Value = S.FamilyName;
            app.AuthorEmailEditField.Value = S.ContactInformation;
            app.DigitalIdentifierORCIDEditField.Value = S.DigitalIdentifier;
            app.AffiliationTable.Data = [];
            for i = 1:numel(S.Affiliation)
                app.addAffiliationToTable(S(i).Affiliation)
            end
            cbt = S.AuthorRole;
            app.AuthorRoleTree.CheckedNodes = cbt;
            app.AdditionalDetailsTextArea.Value = S.Additional;
        end

        % Update the author listbox with the full names of all existing authors
        function updateAuthorListbox(app)
            S = app.AuthorData.AuthorList;
            fullNames = arrayfun(@(i) app.AuthorData.getAuthorName(i), 1:numel(S), 'UniformOutput', false);
            app.AuthorListBox.Items = fullNames;
            app.AuthorListBox.Value = fullNames{1};
        end

        function onAuthorNameChanged(app, fieldName, value)
            
            %app.updatePerson(fieldName, value)
            authorIndex = app.getSelectedAuthorIndex();

            app.AuthorData.updateProperty(fieldName, value, authorIndex)
            fullName = app.AuthorData.getAuthorName(authorIndex);

            app.AuthorListBox.Items{authorIndex} = fullName;
            app.AuthorListBox.Value = fullName;
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Check if any ORCID belongs to someone perfectly matching the
        % given name.
        function checkOrcidMatch(app, fullName)
            try
                orcid = ndi.database.metadata_app.fun.getOrcId(fullName);
                if ~isempty(orcid)
                    orcidLink = sprintf("https://orcid.org/%s", orcid);
                    msg = sprintf('The following ORCID <a href="%s">%s</a> was found matching the given author name. Please use the link above to check if the information matches the intended author', orcidLink, orcid);
                    answer = uiconfirm(app.NDIDatasetWizardUIFigure, msg, "Review ORCID", "Options", {'Confirm', 'Reject'}, 'CancelOption', 'Reject', 'Interpreter', 'html');
                    if strcmp( answer, 'Confirm' )
                        app.DigitalIdentifierORCIDEditField.Value = orcidLink;

                        authorIndex = app.getSelectedAuthorIndex();
                        app.AuthorData.updateProperty('DigitalIdentifier', orcidLink, authorIndex);
                        app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                        app.saveDatasetInformation('Author');
                    end
                end 
            catch
                % Pass for now
            end
        end

        % Add author details to the authors table.
        function addAffiliationToTable(app, S)
            newRowData = struct2table(S, 'AsArray', true);
            newRowData.RORId = [];
            % if isempty(app.AffiliationTable.Data)
            %     app.AffiliationTable.ColumnEditable = [true, true, false]; %Temp, testing..
            % end
            app.AffiliationTable.Data = cat(1, app.AffiliationTable.Data, newRowData);
        end

        % Replace information for specified author in the author table
        function replaceAffiliationInTable(app, S, affiliationIndex)
            newRowData = struct2table(S, 'AsArray', true);
            app.AffiliationTable.Data(affiliationIndex, :) = newRowData;
        end

        % function deleteAffiliationInArray(app, authorIndex, affiliationIndex)
        %     app.AuthorData.AuthorList(authorIndex).Affiliation(affiliationIndex) = [];
        % end 

        % Open (external app) form where user can enter author details
        function openAffiliationForm(app, affiliationInfo, affiliationIndex)
        %openAffiliationForm Open Affiliation form where user can enter Affiliation details
            if ~isfield(app.UIForm, 'Affiliation')
                app.UIForm.Affiliation = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
            else
                app.UIForm.Affiliation.Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(affiliationInfo)
                % Update form information if we are editing an affiliation
                authorIndex = app.getSelectedAuthorIndex();
                app.AuthorData.updateProperty('Affiliation', affiliationInfo, authorIndex)
                app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                % app.AuthorData.setAffiliation(authorIndex, affiliationInfo);
                % app.UIForm.Author.setAuthorDetails(authorInfo);
            end

            app.UIForm.Affiliation.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Affiliation.getAffiliationDetails();
            authorIndex = app.getSelectedAuthorIndex();
            
            % Update data in table if user pressed save.
            mode = app.UIForm.Affiliation.FinishState;
            if mode == "Save"
                if nargin > 1
                    app.replaceAffiliationInTable(S, affiliationIndex)
                else
                    app.addAffiliationToTable(S);
                end
            end
            
            app.AuthorData.addAffiliation(authorIndex, S);
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');

            app.UIForm.Affiliation.reset()
            app.UIForm.Affiliation.Visible = 'off'; % Hide the form (for later reuse)
        end


        % Replace information for specified author in the author table
        function replaceProbeInTable(app, probeIndex, probe)
            newRowData = struct2table(probe.toTableStruct(),'AsArray', true);
            cellData = table2cell(newRowData);
            app.UITableProbe.Data(probeIndex, :) = cellData;
        end

        function openFundingForm(app, fundingName)
        %openAffiliationForm Open Affiliation form where user can enter Affiliation details
            if ~isfield(app.UIForm, 'Funding')
                app.UIForm.Funding = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
            else
                app.UIForm.Funding.Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(fundingName)
                % app.Funding.updateProperty('Affiliation', affiliationInfo, authorIndex)
                % app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                % app.AuthorData.setAffiliation(authorIndex, affiliationInfo);
                % app.UIForm.Author.setAuthorDetails(authorInfo);
                app.UIForm.Funding.setName(fundingName);
            end

            app.UIForm.Funding.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Funding.getAffiliationDetails();
           
            % Update data in table if user pressed save.
            mode = app.UIForm.Funding.FinishState;
            if mode == "Save"
                app.FundingEditField.Value = S.AffiliationName;
            end
            app.Funding = S;
            app.saveDatasetInformation('Funding');

            app.UIForm.Funding.reset()
            app.UIForm.Funding.Visible = 'off'; % Hide the form (for later reuse)
        end

        % Open (external app) form where user can enter probe details
        function openProbeForm(app, probeType, probeIndex, probe)
        %openProbeForm Open probe form where user can enter probe details
            switch probeType
                case "Electrode"
                    if ~isfield(app.UIForm, 'Electrode')
                        app.UIForm.Electrode = ndi.database.metadata_app.Apps.ElectrodeForm();
                    else
                        app.UIForm.Electrode.Visible = 1;
                    end
                    form = app.UIForm.Electrode;
                case "Pipette"
                    if ~isfield(app.UIForm, 'Pipette')
                        app.UIForm.Pipette = ndi.database.metadata_app.Apps.PipetteForm();
                    else
                        app.UIForm.Pipette.Visible = 1;
                    end
                    form = app.UIForm.Pipette;
                if nargin > 3
                    form.setProbeDetails(probe);
                end
            end

            if nargin > 3 && ~isempty(probe)
                % Update form information if we are editing an probe
                form.setProbeDetails(probe);
            end

            form.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            newProbe = form.getProbeDetails();

            % Update data in table if user pressed save.
            mode = form.FinishState;
            if mode == "Save"
                app.ProbeData.replaceProbe(probeIndex, newProbe);
                app.replaceProbeInTable(probeIndex, newProbe);
                app.saveDatasetInformation('Probe');
            end

            form.reset()
            form.Visible = 'off'; % Hide the form (for later reuse)
        end
    end
    
    methods (Access = private)
        
        function setFigureMinSize(app)
            isMatch = false;
            drawnow
            while ~any(isMatch)
                windowList = matlab.internal.webwindowmanager.instance.findAllWebwindows();
                isMatch = strcmp({windowList.Title}, app.NDIDatasetWizardUIFigure.Name);
            end
            window = windowList(isMatch);
            window.setMinSize([640 480])
        end

        function filePath = getDatasetFilepath(app)

            filePath = app.FilePath;
            if isempty(filePath)
                filePath = fullfile(userpath, 'NDIDatasetUpload', 'dataset.mat');
            end
        end

        function resetNameIn(app, name, numSeconds)
        % Use a time to reset figure name
            if ~isempty(app.Timer)
                stop(app.Timer)
                delete(app.Timer)
                app.Timer = [];
            end

            app.Timer = timer();
            app.Timer.TimerFcn = @(s,e)app.updateName(name);
            app.Timer.StartDelay = 5;
            start(app.Timer)
        end

        function updateName(app, name)
        % Update figure name and delete timer.
            app.NDIDatasetWizardUIFigure.Name = name;
            stop(app.Timer)
            delete(app.Timer)
            app.Timer = [];
        end
    
        function changeTab(app, newTab)
            app.TabGroup.SelectedTab = newTab;
            app.onTabChanged()
        end

        function onTabChanged(app, selectedTab)
        % onTabChanged - Callback for when current tab is changed.
            
            if nargin < 2 
                selectedTab = app.TabGroup.SelectedTab;
            end

            if selectedTab == app.TabGroup.Children(1) ...
                    || selectedTab == app.TabGroup.Children(end)
                
                app.FooterPanel.Visible = 'off';
                app.FooterPanel.Parent = app.NDIDatasetWizardUIFigure;

                app.MainGridLayout.RowHeight = {'1x'};
            else
                app.FooterPanel.Visible = 'on';
                app.MainGridLayout.RowHeight = {'1x', 63};
                app.FooterPanel.Parent = app.MainGridLayout;
                app.FooterPanel.Layout.Row = 2;
                app.FooterPanel.Layout.Column = 1;
            end
        end

        function updateSubjectTableColumData(app, columnName, newValue)
                
            % Get the selected row and column in the table
            selectedRows = app.UITableSubject.Selection;
            prevData = app.SubjectData;
            
            for i = 1:numel(selectedRows)

                subjectIndex = selectedRows(i);
                subjectName = app.UITableSubject.Data{selectedRows(i), 'Subject'};
                % subjectIndex = app.SubjectData.getIndex(subjectName); %Question: Would this ever be different from selected row?

                if ~isempty(newValue) && ~isempty(subjectName) && subjectIndex ~= -1          
                    switch columnName
                        case 'BiologicalSex'
                            app.SubjectData.SubjectList(subjectIndex).BiologicalSexList = {newValue};
                            
                        case 'Species'
                            species_name = app.SpeciesListBox.Value;
                            species = app.SpeciesData.getItem(species_name);
                            if ~app.SubjectData.biologicalSexSelected(subjectName) && ~isempty(species) && isa(species, 'ndi.database.metadata_app.class.Species')
                                app.SubjectData = prevData;
                                data = app.SubjectData.formatTable();
                                app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                                errMessage = sprintf('Please fill the biological sex for the subjects selected.');
                                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing biological sex');
                                break;
                            else
                                app.SubjectData.SubjectList(subjectIndex).SpeciesList = species;
                            end

                        case 'Strain'
                            strainName = app.StrainListBox.Value;
                            if isempty(strainName)
                                app.SubjectData = prevData;
                                data = app.SubjectData.formatTable();
                                app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                                errMessage = sprintf('Please select a valid strain.');
                                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing strain');
                                break;
                            end
                            if ~app.SubjectData.SpeciesSelected(subjectName)
                                app.SubjectData = prevData;
                                data = app.SubjectData.formatTable();
                                app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                                errMessage = sprintf('Please fill the species for the subjects selected.');
                                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing species');
                                break;
                            else
                                app.SubjectData.SubjectList(subjectIndex).addStrain(strainName);
                            end
                     
                    end
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                end
            end
            app.saveDatasetInformation('Subject');
        end

        function deleteSubjectTableColumData(app, columnName)
                
            % Get the selected row and column in the table
            selectedRows = app.UITableSubject.Selection;
            prevData = app.SubjectData;
            
            for i = 1:numel(selectedRows)

                subjectIndex = selectedRows(i);
                subjectName = app.UITableSubject.Data{selectedRows(i), 'Subject'};

                if ~isempty(subjectName) && subjectIndex ~= -1          
                    switch columnName
                        case 'BiologicalSex'
                            app.SubjectData.SubjectList(subjectIndex).deleteBiologicalSex();
                            
                        case 'Species'
                            app.SubjectData.SubjectList(subjectIndex).deleteSpeciesList();

                        case 'Strain'
                            app.SubjectData.SubjectList(subjectIndex).deleteStrainList();
                    end
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                else
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    errMessage = sprintf('Please select a valid subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'invalid selection');
                end
            end
            app.saveDatasetInformation('Subject');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sessionObject, filePath)
            
            arguments 
                app (1,1) ndi.database.metadata_app.Apps.DatasetUploadApp
                sessionObject (1,:) ndi.session = ndi.session.empty % Todo: should be required.
                filePath (1,:) string = string.empty
            end
        
            if ~isempty(sessionObject)
                app.Session = sessionObject;
            end
        
            if ~isempty(filePath)
                app.FilePath = filePath;
            end

            app.FooterPanel.Visible = 'off';

            % app.setFigureMinSize()
            app.UITableSubject.SelectionType = "row";

             %check if app has a Session field
            if ~isempty(app.Session)
                subjectData = ndi.database.metadata_app.fun.loadSubjects(app.Session);
                app.SubjectData = subjectData;
                subjectTableData = subjectData.formatTable();
                app.UITableSubject.Data = struct2table(subjectTableData, 'AsArray', true);

                probeData = ndi.database.metadata_app.fun.loadProbes(app.Session);
                app.ProbeData = probeData;
                probeTableData = probeData.formtaTable();
                app.UITableProbe.Data = struct2table(probeTableData, 'AsArray', true);
            end

            try
                ndi.fun.assertAddonOnPath("openMINDS Metadata Models", 'RequiredFor', 'NDI Dataset Wizard')
            catch ME
                delete(app)
                throwAsCaller(ME)
            end

            ndi.database.metadata_app.fun.loadExperimentalApproach(app.ExperimentalApproachTree);
            ndi.database.metadata_app.fun.loadTechniquesEmployed(app.TechniquesEmployedTree);
          
            % app.loadDatasetInformation()
        end

        % Close request function: NDIDatasetWizardUIFigure
        function NDIDatasetWizardUIFigureCloseRequest(app, event)
            formNames = fieldnames(app.UIForm);
            for i = 1:numel(formNames)
                thisName = formNames{i};
                delete(app.UIForm.(thisName))
            end
            delete(app)
        end

        % Button pushed function: AddAuthorButton
        function AddAuthorButtonPushed2(app, event)
            % Add item to listbox
            numAuthors = numel(app.AuthorListBox.Items);
            app.AuthorListBox.Items{end+1} = sprintf('Author %d', numAuthors+1);
            app.AuthorListBox.Value = app.AuthorListBox.Items{end};
            app.AuthorListBoxValueChanged();
        end

        % Button pushed function: RemoveAuthorButton
        function RemoveAuthorButtonPushed(app, event)
            % Remove item from listbox
            isSelected = strcmp(app.AuthorListBox.Items, app.AuthorListBox.Value);
            
            newItems = app.AuthorListBox.Items(~isSelected);
            
            % Remove author from list in author data
            app.AuthorData.removeItem(find(isSelected))
            
            % Update items in listbox
            app.AuthorListBox.Value = newItems{1};
            app.AuthorListBox.Items = newItems;

            % Update all input fields to reflect new selection 
            S = app.AuthorData.getItem(1);
            app.fillAuthorInputFieldsFromStruct(S)

            app.saveDatasetInformation('Author')
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
        end

        % Value changed function: GivenNameEditField
        function GivenNameEditFieldValueChanged(app, event)
            newValue = app.GivenNameEditField.Value;
            app.onAuthorNameChanged('GivenName', newValue)

            authorIndex = app.getSelectedAuthorIndex();
            fullName = app.AuthorData.getAuthorName(authorIndex);
            app.checkOrcidMatch(fullName) % Only do for value changed
        end

        % Value changing function: GivenNameEditField
        function GivenNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.onAuthorNameChanged('GivenName', changingValue)
        end

        % Value changed function: FamilyNameEditField
        function FamilyNameEditFieldValueChanged(app, event)
            newValue = app.FamilyNameEditField.Value;
            app.onAuthorNameChanged('FamilyName', newValue)
               
            authorIndex = app.getSelectedAuthorIndex();
            fullName = app.AuthorData.getAuthorName(authorIndex);
            app.checkOrcidMatch(fullName)
        end

        % Value changing function: FamilyNameEditField
        function FamilyNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.onAuthorNameChanged('FamilyName', changingValue)
        end

        % Value changed function: AuthorEmailEditField
        function AuthorEmailEditFieldValueChanged(app, event)
            value = app.AuthorEmailEditField.Value;
            try
                mustBeValidEmail(value)
            catch ME
                uialert(app.NDIDatasetWizardUIFigure, ME.message, 'Invalid email')
            end
            %app.updatePerson('contactInformation', contactInformation)
            
            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateProperty('ContactInformation', value, authorIndex)
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.DatasetVersionData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Value changed function: DigitalIdentifierORCIDEditField
        function DigitalIdentifierORCIDEditFieldValueChanged(app, event)
            value = app.DigitalIdentifierORCIDEditField.Value;
            if ~isempty(value)
                try
                    if ~contains(value, 'https://orcid.org/')
                        value = sprintf('https://orcid.org/%s', value);
                    end
                    orcid = openminds.core.ORCID('identifier',value);
                    app.updatePerson('digitalIdentifier', orcid)
                    app.DigitalIdentifierORCIDEditField.Value = value;
                catch ME
                    errMessage = sprintf('The entered value is not a valid ORCID. For examples of valid ORCID, please see this <a href=https://support.orcid.org/hc/en-us/articles/360006897674-Structure-of-the-ORCID-Identifier#3-some-sample-orcid-ids>link</a>');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid ORCID', 'Interpreter', 'html')
                    %uialert(app.NDIDatasetWizardUIFigure, ME.message, 'Invalid input')
                end
            end
            
            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateProperty('DigitalIdentifier', value, authorIndex)
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author')
        end

        % Value changed function: AuthorListBox
        function AuthorListBoxValueChanged(app, event)
            authorIndex = app.getSelectedAuthorIndex();

            S = app.AuthorData.getItem(authorIndex);
            app.fillAuthorInputFieldsFromStruct(S) % Update the author fields
        end

        % Button pushed function: SearchOrcidButton
        function SearchOrcidButtonPushed(app, event)
            authorIndex = app.getSelectedAuthorIndex();
            fullName = app.AuthorData.getAuthorName(authorIndex);
            apiQueryUrl = ndi.database.metadata_app.fun.getOrcIdSearchUrl(fullName);
            web(apiQueryUrl)
        end

        % Callback function: AuthorRoleTree
        function AuthorRoleTreeCheckedNodesChanged(app, event)
            app.onTabChanged(app.DatasetOverviewTab)
            drawnow
            app.TabGroup.SelectedTab = app.DatasetOverviewTab;

        end

        % Button pushed function: AddAffiliationButton
        function AddAffiliationButtonPushed(app, event)
            app.openAffiliationForm();
        end

        % Button pushed function: RemoveAffiliationButton
        function RemoveAffiliationButtonPushed(app, event)
            if ~isempty(app.AffiliationTable.Data)
                selectedIndices = app.AffiliationTable.Selection(1);
                app.AffiliationTable.Data(selectedIndices,:) = [];
                authorIndex = app.getSelectedAuthorIndex();
                app.AuthorData.removeAffiliation(authorIndex, selectedIndices);
                app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                app.saveDatasetInformation('Author');
            end
        end

        % Value changed function: AdditionalDetailsTextArea
        function AdditionalDetailsValueChanged(app, event)
            value = app.AdditionalDetailsTextArea.Value;
            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateProperty('Additional', value, authorIndex);
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            app.onTabChanged(app.DatasetOverviewTab)
            app.TabGroup.SelectedTab = app.DatasetOverviewTab;
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            app.NDIDatasetWizardUIFigure.Name = 'NDI Dataset Wizard (Saving...)';
            app.saveDatasetInformation()
            app.NDIDatasetWizardUIFigure.Name = 'NDI Dataset Wizard (Saved changes)';
            app.resetNameIn('NDI Dataset Wizard', 3)
        end

        % Callback function
        function SubmitButtonPushed(app, event)
            ndi.database.fun.dataset_metadata(app.Session, app.FilePath, 'action', 'submit', 'AuthorData', app.AuthorData);
            % app.AuthorData
        end

        % Value changed function: DatasetBranchTitleEditField
        function DatasetBranchTitleValueChanged(app, event)
            value = app.DatasetBranchTitleEditField.Value;
            app.DatasetData.updateProperty("FullName",value);
            app.ShortNameEditField.Value = ndi.database.metadata_app.fun.generateShortName(value, 3);
            app.DatasetData.updateProperty("ShortName",value);
            
            app.DatasetBranchTitle = value;
            app.saveDatasetInformation('DatasetBranchTitle');
            app.DatasetShortName = app.ShortNameEditField.Value;
            app.saveDatasetInformation('DatasetShortName');
        end

        % Value changed function: ShortNameEditField
        function DatasetShortNameValueChanged(app, event)
            value = app.ShortNameEditField.Value;
            app.DatasetData.updateProperty("ShortName",value);

            app.DatasetShortName = app.ShortNameEditField.Value;
            app.saveDatasetInformation('DatasetShortName');
        end

        % Value changed function: AbstractTextArea
        function AbstractValueChanged(app, event)
            value = app.AbstractTextArea.Value;
            app.DatasetData.updateProperty("Description",value);

            app.Abstract = value;
            app.saveDatasetInformation('Abstract');
        end

        % Value changed function: CommentsDetailsTextArea
        function CommentsDetailsValueChanged(app, event)
            value = app.CommentsDetailsTextArea.Value;
            app.Comments = value;
            app.saveDatasetInformation('Comments');
        end

        % Value changed function: LicenseDropDown
        function LicenseDropDownValueChanged(app, event)
            value = app.LicenseDropDown.Value;
            license = ndi.database.metadata_app.class.License(value);
            app.License = license;
            app.saveDatasetInformation('License');
        end

        % Value changed function: FullDocumentationEditField
        function FullDocumentationValueChanged(app, event)
            value = app.FullDocumentationEditField.Value;
            app.FullDocumentation = value;
            app.saveDatasetInformation('FullDocumentation');
        end

        % Value changed function: FundingEditField
        function FundingValueChanged(app, event)
            value = app.FundingEditField.Value;
            app.openFundingForm(value);
        end

        % Button pushed function: AddRelatedPublicationButton
        function AddRelatedPublicationButtonPushed(app, event)
            currentData = app.RelatedPublicationUITable.Data;
            newRow = {'', '', '', ''};
            currentData = [currentData; newRow];
            app.RelatedPublicationUITable.Data = currentData;
            app.RelatedPublication = currentData;
        end

        % Cell edit callback: RelatedPublicationUITable
        function RelatedPublicationCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            currentData = app.RelatedPublicationUITable.Data;
            app.RelatedPublication = currentData;
            app.saveDatasetInformation('RelatedPublication');
        end

        % Value changed function: VersionIdentifierEditField
        function VersionIdentifierValueChanged(app, event)
            value = app.VersionIdentifierEditField.Value;
            app.VersionIdentifier = value;
            app.saveDatasetInformation('VersionIdentifier');
        end

        % Value changed function: VersionInnovationEditField
        function VersionInnovationValueChanged(app, event)
            value = app.VersionInnovationEditField.Value;
            disp(value)
            app.VersionInnovation = value;
            app.saveDatasetInformation('VersionInnovation');
        end

        % Callback function: DataTypeTree
        function DataTypeTreeCheckedNodesChanged(app, event)
            checkedNodes = event.CheckedNodes;
            selectedDataTypes = {checkedNodes.Text};
            app.DataType = selectedDataTypes;
            app.saveDatasetInformation('DataType');
        end

        % Callback function: TechniquesEmployedTree
        function TechniqueTreeCheckedNodesChanged(app, event)
            checkedNodes = event.CheckedNodes;
            selectedTechniques = {checkedNodes.Text};
            app.TechniquesEmployed = selectedTechniques;
            app.saveDatasetInformation('TechniquesEmployed');
        end

        % Callback function: ExperimentalApproachTree
        function ExperimentTreeCheckedNodesChanged(app, event)
            checkedNodes = event.CheckedNodes;
            selectedExperimentalApproach = {checkedNodes.Text};
            app.ExperimentalApproach = selectedExperimentalApproach;
            app.saveDatasetInformation('ExperimentalApproach');
        end

        % Value changed function: ReleaseDateDatePicker
        function ReleaseDateValueChanged(app, event)
            value = app.ReleaseDateDatePicker.Value;
            app.ReleaseDate = value;
            app.saveDatasetInformation('ReleaseDate');
        end

        % Button pushed function: AddSpeciesButton
        function AddSpeciesButtonPushed(app, event)
            value = app.SpeciesEditField.Value;
            [~, uuid] = ndi.database.metadata_app.fun.SearchSpecies(value);
            if (uuid == -1)
                errMessage = sprintf('The entered value is not a valid scientific name, common name or synonym.');
                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid species name');
            else
                [name, ontology_identifier, synonym] = ndi.database.metadata_app.fun.getSpeciesInfo(uuid);
                app.NameEditField_4.Value = name;
                app.OntologyIdentifierEditField.Value = ontology_identifier;
                app.SynonymEditField_3.Value = synonym;
                app.SpeciesData.addItem(name,synonym,ontology_identifier);
                app.saveDatasetInformation('Species');
                if (numel(app.SpeciesListBox.Items)) == 0
                    app.SpeciesListBox.Items = name;
                else
                    app.SpeciesListBox.Items{end+1} = sprintf('%s', name);
                end
                
                app.SpeciesListBox.Value = app.SpeciesListBox.Items{end};
            end
        
        end

        % Button pushed function: AssignSpeciesButton
        function AssignSpeciesButtonPushed(app, event)
            name = app.SpeciesListBox.Value;
            species = app.SpeciesData.getItem(name);

            subjectSelections = app.UITableSubject.Selection;
            prevData = app.SubjectData;

            for i = 1:size(subjectSelections,1)
                selectedRow = app.UITableSubject.Selection(i,1);
                selectedColumn = app.UITableSubject.Selection(i,2);
                if ~selectedColumn == 1
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid subject');
                    break;
                end

                subjectName = app.UITableSubject.Data(selectedRow, selectedColumn).Subject;
                if isempty(subjectName) || (app.SubjectData.getIndex(subjectName) == -1)
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid subject');
                    break;
                end
                
                if ~app.SubjectData.biologicalSexSelected(subjectName)
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please fill the biological sex for the subjects selected.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing biological sex');
                    break;
                end

                if ~isempty(species) && isa(species, 'ndi.database.metadata_app.class.Species')
                    index = app.SubjectData.getIndex(subjectName);
                    app.SubjectData.SubjectList(index).SpeciesList = species;
                    app.saveDatasetInformation('Subject');
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                else
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid species.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Missing species');
                    % msgbox('Please select a valid biological sex and subject.');
                    break;
                end
            end

        end

        % Button pushed function: AssignBiologicalSexButton
        function AssignBiologicalSexButtonPushed(app, event)
            
            biologicalSex = app.BiologicalSexListBox.Value;    
            
            % Get the selected row and column in the table
            subjectSelections = app.UITableSubject.Selection;
            prevData = app.SubjectData;
            
            for i = 1:size(subjectSelections,1)
                selectedRow = app.UITableSubject.Selection(i,1);
                %selectedColumn = app.UITableSubject.Selection(i,2);
                subjectName = app.UITableSubject.Data{selectedRow, 'Subject'};
                
                % Check if a biological sex is selected and a valid subject is chosen
                if ~isempty(biologicalSex) && ~isempty(subjectName) && ~(app.SubjectData.getIndex(subjectName) == -1)
                    % Assign the selected biological sex to the corresponding row and "Biological Sex" column
                    index = app.SubjectData.getIndex(subjectName);
                    app.SubjectData.SubjectList(index).BiologicalSexList = {biologicalSex};
                    app.saveDatasetInformation('Subject');
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                else
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid biological sex and subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid biological sex or subject');
                    % msgbox('Please select a valid biological sex and subject.');
                end
            end
        end

        % Value changed function: BiologicalSexListBox
        function BiologicalSexListBoxValueChanged(app, event)
            % value = app.BiologicalSexListBox.Value;
            % app.updateSubjectTableColumData('BiologicalSex', value)
        end

        % Clicked callback: BiologicalSexListBox
        function BiologicalSexListBoxClicked(app, event)
            drawnow;
            value = event.Source.Value;
            app.updateSubjectTableColumData('BiologicalSex', value);
        end

        % Value changed function: SpeciesListBox
        function SpeciesListBoxValueChanged(app, event)
            % value = app.SpeciesListBox.Value;
            % app.updateSubjectTableColumData('Species', value);
        end

        % Clicked callback: SpeciesListBox
        function SpeciesListBoxClicked(app, event)
            drawnow;
            value = event.Source.Value;
            app.updateSubjectTableColumData('Species', value);
        end

        % Value changed function: StrainListBox
        function StrainListBoxValueChanged(app, event)
            % value = app.StrainListBox.Value;
            % app.updateSubjectTableColumData('Strain', value)
        end

        % Clicked callback: StrainListBox
        function StrainListBoxClicked(app, event)
            drawnow;
            value = event.Source.Value;
            app.updateSubjectTableColumData('Strain', value);
        end

        % Button pushed function: StrainClearButton
        function StrainClearButtonPushed(app, event)
            app.deleteSubjectTableColumData("Strain");
        end

        % Button pushed function: SpeciesClearButton
        function SpeciesClearButtonPushed(app, event)
            app.deleteSubjectTableColumData("Species");
        end

        % Button pushed function: BiologicalSexClearButton
        function BiologicalSexClearButtonPushed(app, event)
            app.deleteSubjectTableColumData("BiologicalSex");
        end

        % Callback function
        function ProbeListBoxValueChanged(app, event)
            value = app.ProbeListBox.Value;
            app.TypeofProbeListBox.Value = {};
        end

        % Callback function
        function UITableProbeCellEdit(app, event)
            indices = event.Indices;
            probeType = event.NewData;
            probeIndex = indices(1);
            if ~app.ProbeData.probeExist(probeIndex)
                app.openProbeForm(probeType,probeIndex);
            else
                message = 'You have already select the probe type. Do you really want to change it?';
                title = 'Confirm selection';
                userResponse = uiconfirm(app.NDIDatasetWizardUIFigure, message, title, 'Options', {'Yes', 'No'}, 'DefaultOption', 'No');
                % Check the user's response
                if strcmp(userResponse, 'Yes')
                    app.openProbeForm(probeType,probeIndex);
                else
                    app.UITableProbe.Data{indices(1),indices(2)} = eventdata.PreviousData;
                end
            end            
        end

        % Double-clicked callback: UITableProbe
        function UITableProbeDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            classType = app.ProbeData.ProbeList{displayRow}.ClassType;
            app.openProbeForm(classType,displayRow, app.ProbeData.ProbeList{displayRow});
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.onTabChanged(selectedTab)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            currentTab = app.TabGroup.SelectedTab;
            tabIdx = find( ismember( app.TabGroup.Children, currentTab ) );
            newTabIdx = tabIdx - 1;
            newTab = app.TabGroup.Children(newTabIdx);
            
            app.onTabChanged(newTab)
            drawnow
            app.TabGroup.SelectedTab = newTab;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            currentTab = app.TabGroup.SelectedTab;
            tabIdx = find( ismember( app.TabGroup.Children, currentTab ) );
            newTabIdx = tabIdx + 1;
            newTab = app.TabGroup.Children(newTabIdx);
            
            app.onTabChanged(newTab)
            drawnow
            app.TabGroup.SelectedTab = newTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NDIDatasetWizardUIFigure = uifigure('Visible', 'off');
            app.NDIDatasetWizardUIFigure.Name = 'NDI Dataset Wizard';
            app.NDIDatasetWizardUIFigure.CloseRequestFcn = createCallbackFcn(app, @NDIDatasetWizardUIFigureCloseRequest, true);
            app.FundingListBoxLabel = uilabel(app.NDIDatasetWizardUIFigure);
            app.FundingListBoxLabel.Text = 'Funding';
            app.FundingListBox = uilistbox(app.NDIDatasetWizardUIFigure);
            app.FundingListBox.Items = {'Item 1', 'Item 2'};
            app.DatasetIdentifierEditFieldLabel = uilabel(app.NDIDatasetWizardUIFigure);
            app.DatasetIdentifierEditFieldLabel.Text = 'Dataset Identifier';
            app.DatasetIdentifierEditField = uieditfield(app.NDIDatasetWizardUIFigure, 'text');
            app.DatasetIdentifierEditField.InputType = 'alphanumerics';
            app.DatasetIdentifierEditField.Tooltip = {'Auto-generated digital nametag for your dataset, no input required'};
            app.DatasetIdentifierEditField.Value = 'GENERATE';
            app.FooterpanelLabel = uilabel(app.NDIDatasetWizardUIFigure);
            app.FooterpanelLabel.Text = 'Footer panel';
            app.SaveChangesButton = uibutton(app.NDIDatasetWizardUIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.MainGridLayout = uigridlayout(app.NDIDatasetWizardUIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.GridLayout_Step0 = uigridlayout(app.IntroTab);
            app.GridLayout_Step0_C3 = uigridlayout(app.GridLayout_Step0);
            app.GetStartedButton = uibutton(app.GridLayout_Step0_C3, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.WelcomeLabel = uilabel(app.GridLayout_Step0);
            app.WelcomeLabel.Text = {'Welcome to the NDI Cloud''s Upload Tool!'; ''};
            app.GridLayout_Step0_C2 = uigridlayout(app.GridLayout_Step0);
            app.Label_3 = uilabel(app.GridLayout_Step0_C2);
            app.Label_3.Text = {''; 'We''re excited to have you here. This is your upload form, where you can effortlessly share your data with us. Over the next few pages, we''ll guide you through the process of ingesting your valuable data. Our goal is to make this as seamless as possible, ensuring that your information is accurately processed and ready for analysis. '; ''; 'If you ever need assistance with any of the form elements, simply hover over the respective item to access helpful information. For any further queries, shoot us an email at info@walthamdatascience.com and we''ll get right back to you! Thank you for choosing our app to help you manage your data. Let''s get started on this journey together!'};
            app.DatasetOverviewTab = uitab(app.TabGroup);
            app.DatasetOverviewTab.Title = 'Dataset Overview';
            app.GridLayout_Step1 = uigridlayout(app.DatasetOverviewTab);
            app.DatasetInformationPanel = uipanel(app.GridLayout_Step1);
            app.GridLayout = uigridlayout(app.DatasetInformationPanel);
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.AbstractTextArea = uitextarea(app.GridLayout3);
            app.AbstractTextArea.ValueChangedFcn = createCallbackFcn(app, @AbstractValueChanged, true);
            app.AbstractLabel = uilabel(app.GridLayout3);
            app.AbstractLabel.Text = 'Abstract';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.ShortNameEditField = uieditfield(app.GridLayout2, 'text');
            app.ShortNameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetShortNameValueChanged, true);
            app.ShortNameEditField.Tooltip = {'If multiple, separate by comma '};
            app.DatasetBranchTitleEditField = uieditfield(app.GridLayout2, 'text');
            app.DatasetBranchTitleEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetBranchTitleValueChanged, true);
            app.DatasetBranchTitleLabel = uilabel(app.GridLayout2);
            app.DatasetBranchTitleLabel.Text = 'Dataset Branch Title';
            app.DatasetShortNameLabel = uilabel(app.GridLayout2);
            app.DatasetShortNameLabel.Text = 'Dataset Short Name';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.CommentsDetailsLabel = uilabel(app.GridLayout4);
            app.CommentsDetailsLabel.Text = 'Comments/Details';
            app.CommentsDetailsTextArea = uitextarea(app.GridLayout4);
            app.CommentsDetailsTextArea.ValueChangedFcn = createCallbackFcn(app, @CommentsDetailsValueChanged, true);
            app.CommentsDetailsTextArea.Tooltip = {'Anything else you''d like us to know about your upload? Note: This field is not public facing.'};
            app.DatasetInformationLabel = uilabel(app.GridLayout_Step1);
            app.DatasetInformationLabel.Text = 'Dataset Information';
            app.AuthorsTab = uitab(app.TabGroup);
            app.AuthorsTab.Title = 'Authors';
            app.GridLayout_Step2 = uigridlayout(app.AuthorsTab);
            app.AuthorDetailsLabel = uilabel(app.GridLayout_Step2);
            app.AuthorDetailsLabel.Text = 'Author Details';
            app.AuthorDetailsPanel = uipanel(app.GridLayout_Step2);
            app.GridLayout6 = uigridlayout(app.AuthorDetailsPanel);
            app.GridLayout13 = uigridlayout(app.GridLayout6);
            app.AuthorRoleLabel = uilabel(app.GridLayout13);
            app.AuthorRoleLabel.Text = 'Author Role';
            app.AuthorRoleTree = uitree(app.GridLayout13, 'checkbox');
            app.AuthorRoleTree.Tooltip = {'An author can have multiple roles'};
            app.stAuthorNode = uitreenode(app.AuthorRoleTree);
            app.stAuthorNode.Text = '1st Author';
            app.CustodianNode = uitreenode(app.AuthorRoleTree);
            app.CustodianNode.Text = 'Custodian';
            app.CorrespondingNode = uitreenode(app.AuthorRoleTree);
            app.CorrespondingNode.Text = 'Corresponding';
            % Assign Checked Nodes
            app.AuthorRoleTree.CheckedNodesChangedFcn = createCallbackFcn(app, @AuthorRoleTreeCheckedNodesChanged, true);
            app.InstitutesAffiliationsListBoxLabel = uilabel(app.GridLayout13);
            app.InstitutesAffiliationsListBoxLabel.Text = 'Affiliations/Institutes';
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.AffiliationTable = uitable(app.GridLayout14);
            app.AffiliationTable.ColumnName = {'Name'};
            app.AffiliationTable.RowName = {};
            app.GridLayout9_2 = uigridlayout(app.GridLayout14);
            app.AddAuthorButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.AddAuthorButton_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'plus.png');
            app.AddAuthorButton_2.Text = '';
            app.RemoveAuthorButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.RemoveAuthorButton_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'minus.png');
            app.RemoveAuthorButton_2.Text = '';
            app.Button_5 = uibutton(app.GridLayout9_2, 'push');
            app.Button_5.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'up.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout9_2, 'push');
            app.Button_6.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'down.png');
            app.Button_6.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.CreateauthorsandfillouttheirdetailsLabel = uilabel(app.GridLayout7);
            app.CreateauthorsandfillouttheirdetailsLabel.Text = 'Create authors and fill out their details';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.AddAuthorButton = uibutton(app.GridLayout9, 'push');
            app.AddAuthorButton.ButtonPushedFcn = createCallbackFcn(app, @AddAuthorButtonPushed2, true);
            app.AddAuthorButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'plus.png');
            app.AddAuthorButton.Text = '';
            app.RemoveAuthorButton = uibutton(app.GridLayout9, 'push');
            app.RemoveAuthorButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAuthorButtonPushed, true);
            app.RemoveAuthorButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'minus.png');
            app.RemoveAuthorButton.Text = '';
            app.Button_4 = uibutton(app.GridLayout9, 'push');
            app.Button_4.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'up.png');
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.GridLayout9, 'push');
            app.Button_3.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'down.png');
            app.Button_3.Text = '';
            app.AuthorListBox = uilistbox(app.GridLayout8);
            app.AuthorListBox.Items = {};
            app.AuthorListBox.ValueChangedFcn = createCallbackFcn(app, @AuthorListBoxValueChanged, true);
            app.AuthorListBox.Value = {};
            app.GridLayout12 = uigridlayout(app.GridLayout6);
            app.GivenNameEditFieldLabel = uilabel(app.GridLayout12);
            app.GivenNameEditFieldLabel.Text = 'Given Name';
            app.GivenNameEditField = uieditfield(app.GridLayout12, 'text');
            app.GivenNameEditField.ValueChangedFcn = createCallbackFcn(app, @GivenNameEditFieldValueChanged, true);
            app.GivenNameEditField.ValueChangingFcn = createCallbackFcn(app, @GivenNameEditFieldValueChanging, true);
            app.FamilyNameEditFieldLabel = uilabel(app.GridLayout12);
            app.FamilyNameEditFieldLabel.Text = 'Family Name';
            app.FamilyNameEditField = uieditfield(app.GridLayout12, 'text');
            app.FamilyNameEditField.ValueChangedFcn = createCallbackFcn(app, @FamilyNameEditFieldValueChanged, true);
            app.FamilyNameEditField.ValueChangingFcn = createCallbackFcn(app, @FamilyNameEditFieldValueChanging, true);
            app.DigitalIdentifierORCIDLabel = uilabel(app.GridLayout12);
            app.DigitalIdentifierORCIDLabel.Text = 'Digital Identifier (ORCID)';
            app.GridLayout10 = uigridlayout(app.GridLayout12);
            app.DigitalIdentifierORCIDEditField = uieditfield(app.GridLayout10, 'text');
            app.DigitalIdentifierORCIDEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierORCIDEditFieldValueChanged, true);
            app.SearchOrcidButton = uibutton(app.GridLayout10, 'push');
            app.SearchOrcidButton.ButtonPushedFcn = createCallbackFcn(app, @SearchOrcidButtonPushed, true);
            app.SearchOrcidButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearchOrcidButton.Text = '';
            app.AuthorEmailEditField = uieditfield(app.GridLayout12, 'text');
            app.AuthorEmailEditField.ValueChangedFcn = createCallbackFcn(app, @AuthorEmailEditFieldValueChanged, true);
            app.AuthorEmailEditFieldLabel = uilabel(app.GridLayout12);
            app.AuthorEmailEditFieldLabel.Text = 'Author Email';
            app.AdditionalDetailsTextAreaLabel = uilabel(app.GridLayout12);
            app.AdditionalDetailsTextAreaLabel.Text = 'Additional Details';
            app.AdditionalDetailsTextArea = uitextarea(app.GridLayout12);
            app.AdditionalDetailsTextArea.ValueChangedFcn = createCallbackFcn(app, @AdditionalDetailsValueChanged, true);
            app.AdditionalDetailsTextArea.Tooltip = {'Anything else you''d like us to know about this author? Note: This field is not public facing.'};
            app.DatasetDetailsTab = uitab(app.TabGroup);
            app.DatasetDetailsTab.Title = 'Dataset Details';
            app.DATASETDETAILSLabel = uilabel(app.DatasetDetailsTab);
            app.DATASETDETAILSLabel.Text = 'DATASET DETAILS';
            app.ReleaseDateDatePickerLabel = uilabel(app.DatasetDetailsTab);
            app.ReleaseDateDatePickerLabel.Text = 'Release Date';
            app.ReleaseDateDatePicker = uidatepicker(app.DatasetDetailsTab);
            app.ReleaseDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ReleaseDateValueChanged, true);
            app.DataTypeLabel = uilabel(app.DatasetDetailsTab);
            app.DataTypeLabel.Text = 'Data Type';
            app.VersionIdentifierEditFieldLabel = uilabel(app.DatasetDetailsTab);
            app.VersionIdentifierEditFieldLabel.Text = 'Version Identifier';
            app.VersionIdentifierEditField = uieditfield(app.DatasetDetailsTab, 'text');
            app.VersionIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @VersionIdentifierValueChanged, true);
            app.VersionIdentifierEditField.Tooltip = {'If multiple, separate by comma '};
            app.RelatedPublicationUITable = uitable(app.DatasetDetailsTab);
            app.RelatedPublicationUITable.ColumnName = {'Publication'; 'DOI'; 'PMID'; 'PMCID'};
            app.RelatedPublicationUITable.RowName = {};
            app.RelatedPublicationUITable.ColumnEditable = [true true true true];
            app.RelatedPublicationUITable.CellEditCallback = createCallbackFcn(app, @RelatedPublicationCellEdit, true);
            app.RelatedPublicationsLabel = uilabel(app.DatasetDetailsTab);
            app.RelatedPublicationsLabel.Text = {'Related'; 'Publications'};
            app.FullDocumentationEditFieldLabel = uilabel(app.DatasetDetailsTab);
            app.FullDocumentationEditFieldLabel.Text = 'Full Documentation';
            app.FullDocumentationEditField = uieditfield(app.DatasetDetailsTab, 'text');
            app.FullDocumentationEditField.ValueChangedFcn = createCallbackFcn(app, @FullDocumentationValueChanged, true);
            app.FullDocumentationEditField.Tooltip = {'If multiple, separate by comma '};
            app.LicenseDropDownLabel = uilabel(app.DatasetDetailsTab);
            app.LicenseDropDownLabel.Text = 'License';
            app.LicenseDropDown = uidropdown(app.DatasetDetailsTab);
            app.LicenseDropDown.Items = {'CC BY 4.0', 'CC BY-SA 4.0', 'CC BY-NC 4.0', 'CC BY-NC-SA 4.0', 'CC BY-ND 4.0', 'CC BY-NC-ND 4.0'};
            app.LicenseDropDown.ValueChangedFcn = createCallbackFcn(app, @LicenseDropDownValueChanged, true);
            app.LicenseDropDown.Tooltip = {'Choose from one of our approved licenses'};
            app.LicenseDropDown.Value = 'CC BY 4.0';
            app.AddRelatedPublicationButton = uibutton(app.DatasetDetailsTab, 'push');
            app.AddRelatedPublicationButton.ButtonPushedFcn = createCallbackFcn(app, @AddRelatedPublicationButtonPushed, true);
            app.AddRelatedPublicationButton.Text = 'Add';
            app.VersionInnovationEditFieldLabel = uilabel(app.DatasetDetailsTab);
            app.VersionInnovationEditFieldLabel.Text = 'Version Innovation';
            app.VersionInnovationEditField = uieditfield(app.DatasetDetailsTab, 'text');
            app.VersionInnovationEditField.ValueChangedFcn = createCallbackFcn(app, @VersionInnovationValueChanged, true);
            app.VersionInnovationEditField.Tooltip = {'If multiple, separate by comma '};
            app.DataTypeTree = uitree(app.DatasetDetailsTab, 'checkbox');
            app.DataTypeTree.Tooltip = {''};
            app.RawNode = uitreenode(app.DataTypeTree);
            app.RawNode.Text = 'Raw';
            app.DerivedNode = uitreenode(app.DataTypeTree);
            app.DerivedNode.Text = 'Derived';
            app.SimulatedNode = uitreenode(app.DataTypeTree);
            app.SimulatedNode.Text = 'Simulated';
            % Assign Checked Nodes
            app.DataTypeTree.CheckedNodesChangedFcn = createCallbackFcn(app, @DataTypeTreeCheckedNodesChanged, true);
            app.TechniquesEmployedTree = uitree(app.DatasetDetailsTab, 'checkbox');
            app.TechniquesEmployedTree.Tooltip = {''};
            % Assign Checked Nodes
            app.TechniquesEmployedTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TechniqueTreeCheckedNodesChanged, true);
            app.TechniquesEmployedLabel_2 = uilabel(app.DatasetDetailsTab);
            app.TechniquesEmployedLabel_2.Text = 'Techniques Employed';
            app.ExperimentalApproachTree = uitree(app.DatasetDetailsTab, 'checkbox');
            app.ExperimentalApproachTree.Tooltip = {''};
            % Assign Checked Nodes
            app.ExperimentalApproachTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ExperimentTreeCheckedNodesChanged, true);
            app.ExperimentalApproachLabel = uilabel(app.DatasetDetailsTab);
            app.ExperimentalApproachLabel.Text = 'Experimental Approach';
            app.FundingEditFieldLabel = uilabel(app.DatasetDetailsTab);
            app.FundingEditFieldLabel.Text = 'Funding';
            app.FundingEditField = uieditfield(app.DatasetDetailsTab, 'text');
            app.FundingEditField.ValueChangedFcn = createCallbackFcn(app, @FundingValueChanged, true);
            app.ExperimentDetailsTab = uitab(app.TabGroup);
            app.ExperimentDetailsTab.Title = 'Experiment Details';
            app.SubjectInfoTab = uitab(app.TabGroup);
            app.SubjectInfoTab.Title = 'Subject Info';
            app.AssignBiologicalSexButton = uibutton(app.SubjectInfoTab, 'push');
            app.AssignBiologicalSexButton.ButtonPushedFcn = createCallbackFcn(app, @AssignBiologicalSexButtonPushed, true);
            app.AssignBiologicalSexButton.Text = 'Assign';
            app.AssignSpeciesButton = uibutton(app.SubjectInfoTab, 'push');
            app.AssignSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @AssignSpeciesButtonPushed, true);
            app.AssignSpeciesButton.Text = 'Assign';
            app.SpeciesListBox = uilistbox(app.SubjectInfoTab);
            app.SpeciesListBox.Items = {};
            app.SpeciesListBox.ValueChangedFcn = createCallbackFcn(app, @SpeciesListBoxValueChanged, true);
            app.SpeciesListBox.ClickedFcn = createCallbackFcn(app, @SpeciesListBoxClicked, true);
            app.SpeciesListBox.Value = {};
            app.AssignStrainButton = uibutton(app.SubjectInfoTab, 'push');
            app.AssignStrainButton.Text = 'Assign';
            app.BiologicalSexLabel = uilabel(app.SubjectInfoTab);
            app.BiologicalSexLabel.Text = 'Biological Sex';
            app.SpeciesLabel_2 = uilabel(app.SubjectInfoTab);
            app.SpeciesLabel_2.Text = 'Species';
            app.StrainLabel_3 = uilabel(app.SubjectInfoTab);
            app.StrainLabel_3.Text = 'Strain';
            app.BiologicalSexClearButton = uibutton(app.SubjectInfoTab, 'push');
            app.BiologicalSexClearButton.ButtonPushedFcn = createCallbackFcn(app, @BiologicalSexClearButtonPushed, true);
            app.BiologicalSexClearButton.Text = 'CLEAR';
            app.SpeciesClearButton = uibutton(app.SubjectInfoTab, 'push');
            app.SpeciesClearButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesClearButtonPushed, true);
            app.SpeciesClearButton.Text = 'CLEAR';
            app.StrainClearButton = uibutton(app.SubjectInfoTab, 'push');
            app.StrainClearButton.ButtonPushedFcn = createCallbackFcn(app, @StrainClearButtonPushed, true);
            app.StrainClearButton.Text = 'CLEAR';
            app.UITableSubject = uitable(app.SubjectInfoTab);
            app.UITableSubject.ColumnName = {'Subject'; 'Biological Sex'; 'Species'; 'Strain'};
            app.UITableSubject.RowName = {};
            app.SpeciesEditField = uieditfield(app.SubjectInfoTab, 'text');
            app.AddSpeciesButton = uibutton(app.SubjectInfoTab, 'push');
            app.AddSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSpeciesButtonPushed, true);
            app.AddSpeciesButton.Text = 'Add';
            app.BiologicalSexListBox = uilistbox(app.SubjectInfoTab);
            app.BiologicalSexListBox.Items = {'asexual multicellular organism', 'female organism', 'male organism', 'hermaphroditic organism'};
            app.BiologicalSexListBox.ValueChangedFcn = createCallbackFcn(app, @BiologicalSexListBoxValueChanged, true);
            app.BiologicalSexListBox.ClickedFcn = createCallbackFcn(app, @BiologicalSexListBoxClicked, true);
            app.BiologicalSexListBox.Value = 'asexual multicellular organism';
            app.StrainListBox = uilistbox(app.SubjectInfoTab);
            app.StrainListBox.Items = {'conditional knockin ready', 'conditional knockout ready', 'conditional ready', 'knockin', 'knockout', 'transgenic', 'wildtype'};
            app.StrainListBox.ValueChangedFcn = createCallbackFcn(app, @StrainListBoxValueChanged, true);
            app.StrainListBox.ClickedFcn = createCallbackFcn(app, @StrainListBoxClicked, true);
            app.StrainListBox.Value = 'conditional knockin ready';
            app.ProbeInfoTab = uitab(app.TabGroup);
            app.ProbeInfoTab.Title = 'Probe Info';
            app.UITableProbe = uitable(app.ProbeInfoTab);
            app.UITableProbe.ColumnName = {'Probe Name'; 'Probe type'; 'Status'};
            app.UITableProbe.RowName = {};
            app.UITableProbe.ColumnEditable = [false false false];
            app.UITableProbe.DoubleClickedFcn = createCallbackFcn(app, @UITableProbeDoubleClicked, true);
            app.UITableProbe.Multiselect = 'off';
            app.SubmitTab = uitab(app.TabGroup);
            app.SubmitTab.Title = 'Submit';
            app.GridLayout_Step0_2 = uigridlayout(app.SubmitTab);
            app.GridLayout_Step0_C3_2 = uigridlayout(app.GridLayout_Step0_2);
            app.SubmitButton = uibutton(app.GridLayout_Step0_C3_2, 'push');
            app.SubmitButton.Text = 'Submit';
            app.WelcomeLabel_2 = uilabel(app.GridLayout_Step0_2);
            app.WelcomeLabel_2.Text = 'Review and Submit Data';
            app.GridLayout_Step0_C2_2 = uigridlayout(app.GridLayout_Step0_2);
            app.Label_4 = uilabel(app.GridLayout_Step0_C2_2);
            app.Label_4.Text = 'If you have filled out all the information for this dataset, please go ahead with the submission. If you want to review your information one last time, feel free to look through each of the pages again';
            app.Panel_5 = uipanel(app.GridLayout_Step0_C2_2);
            app.ErrorTextAreaLabel = uilabel(app.Panel_5);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.ErrorTextArea = uitextarea(app.Panel_5);
            app.FooterPanel = uipanel(app.NDIDatasetWizardUIFigure);
            app.GridLayout5 = uigridlayout(app.FooterPanel);
            app.PreviousButton = uibutton(app.GridLayout5, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout5, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.AddAffiliationButton = uibutton(app.NDIDatasetWizardUIFigure, 'push');
            app.AddAffiliationButton.ButtonPushedFcn = createCallbackFcn(app, @AddAffiliationButtonPushed, true);
            app.AddAffiliationButton.Text = 'Add';
            app.RemoveAffiliationButton = uibutton(app.NDIDatasetWizardUIFigure, 'push');
            app.RemoveAffiliationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAffiliationButtonPushed, true);
            app.RemoveAffiliationButton.Text = 'Remove';
            % Show the figure after all components are created
            app.NDIDatasetWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetUploadApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NDIDatasetWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NDIDatasetWizardUIFigure)
        end
    end
end