classdef ElectrodeForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UnitListBox                   matlab.ui.control.ListBox
        UnitListBoxLabel              matlab.ui.control.Label
        NoofElectrodesEditFieldLabel  matlab.ui.control.Label
        CompleteCheckBox              matlab.ui.control.CheckBox
        CancelButton                  matlab.ui.control.Button
        CreateButton                  matlab.ui.control.Button
        ManufacturerEditField         matlab.ui.control.EditField
        ManufaturerEditFieldLabel     matlab.ui.control.Label
        DigitalIdentifierEditField    matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField          matlab.ui.control.EditField
        DescriptionEditFieldLabel     matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        IntrinsicResistanceEditField  matlab.ui.control.EditField
        IntrinsicResistanceEditFieldLabel  matlab.ui.control.Label
        ELECTRODELabel                matlab.ui.control.Label
    end

        
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
        Electrode
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end

    methods (Access = public)
    
        function setProbeDetails(app, electrode)
            app.Electrode = electrode;
            app.NameEditField.Value = electrode.Name;
            app.DescriptionEditField.Value = electrode.Description;
            app.DigitalIdentifierEditField.Value = electrode.DigitalIdentifier;
            app.ManufacturerEditField.Value = electrode.Manufacturer;
            app.CompleteCheckBox.Value = electrode.Complete;
            app.IntrinsicResistanceEditField.Value = electrode.IntrinsicResistance;
            app.UnitListBox.Value = electrode.IntrinsicResistanceUnit;

        end

        function electrode = getProbeDetails(app)
            if isempty(app.Electrode)
                electrode = ndi.database.metadata_app.class.Electrode();
            else
                electrode = app.Electrode;
            end
            electrode.updateProperty("Name", app.NameEditField.Value);
            electrode.updateProperty("Description", app.DescriptionEditField.Value);
            electrode.updateProperty("DigitalIdentifier", app.DigitalIdentifierEditField.Value);
            electrode.updateProperty("Manufacturer", app.ManufacturerEditField.Value);
            electrode.updateProperty("Complete", app.CompleteCheckBox.Value);
            electrode.updateProperty("IntrinsicResistance", app.IntrinsicResistanceEditField.Value);
            electrode.updateProperty("IntrinsicResistanceUnit", app.UnitListBox.Value);
        end

        function reset(app)
            app.NameEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.DigitalIdentifierEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.CompleteCheckBox.Value = 0;
            app.IntrinsicResistanceEditField.Value = '';
            app.UnitListBox.Value = {};
            app.FinishState = "";
        end

      
        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.FinishState = "Save";
            app.UIFigureCloseRequest()
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new electrode array?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ELECTRODELabel = uilabel(app.UIFigure);
            app.ELECTRODELabel.Text = 'ELECTRODE';
            app.IntrinsicResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.IntrinsicResistanceEditFieldLabel.Text = 'Intrinsic Resistance';
            app.IntrinsicResistanceEditField = uieditfield(app.UIFigure, 'text');
            app.IntrinsicResistanceEditField.Tooltip = {'Optional'};
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Placeholder = 'Catalog No.';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.DigitalIdentifierEditField.Placeholder = 'DOI/RRID';
            app.ManufaturerEditFieldLabel = uilabel(app.UIFigure);
            app.ManufaturerEditFieldLabel.Text = 'Manufaturer';
            app.ManufacturerEditField = uieditfield(app.UIFigure, 'text');
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CompleteCheckBox = uicheckbox(app.UIFigure);
            app.CompleteCheckBox.Text = '';
            app.NoofElectrodesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofElectrodesEditFieldLabel.Text = 'Complete';
            app.UnitListBoxLabel = uilabel(app.UIFigure);
            app.UnitListBoxLabel.Text = 'Unit';
            app.UnitListBox = uilistbox(app.UIFigure);
            app.UnitListBox.Items = {'ampere', 'arcdegree', 'bit', 'byte', 'centimeter', 'core-hour', 'day', 'degree Celsius', 'degree Fahrenheit', 'degree Rankine', 'embryonic day', 'gigabyte', 'gigaohm', 'gram', 'hour', 'kilobyte', 'kilogram', 'liter', 'megabyte', 'megaohm', 'meter', 'microampere', 'microgram per milliliter', 'micrometer', 'micromolar', 'milligram per kilogram body weight', 'milligram per milliliter', 'milliliter', 'millimeter', 'millimolar', 'millisecond', 'millisiemens', 'millivolt', 'minute', 'molar', 'month', 'nanoampere', 'nanomolar', 'ohm', 'percent', 'picoampere', 'radian', 'second', 'siemens', 'terabyte', 'volt', 'volume per volume percentage', 'wafer-hour', 'week', 'weight per volume percentage', 'weight per weight percentage', 'year'};
            app.UnitListBox.Value = 'ampere';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectrodeForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end