classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NextButton                   matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        AbstractTextArea             matlab.ui.control.TextArea
        AbstractTextAreaLabel        matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        CommentsAdditionalDetailsTextArea  matlab.ui.control.TextArea
        CommentsAdditionalDetailsTextAreaLabel  matlab.ui.control.Label
        DATASETESSENTIALSLabel       matlab.ui.control.Label
        DatasetBranchTitleEditField  matlab.ui.control.EditField
        DatasetBranchTitleEditFieldLabel  matlab.ui.control.Label
        FundingLabel                 matlab.ui.control.Label
        FileSelector                 wt.FileSelector
        LicenseDropDown              matlab.ui.control.DropDown
        LicenseDropDownLabel         matlab.ui.control.Label
        DatasetIdentifierEditField   matlab.ui.control.EditField
        DatasetIdentifierEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Example(app, event)
            
        end

        % Callback function
        function ButtonPushedFcn(app, event)


        end

        % Button pushed function: SaveButton
        function SaveButtonPushedFcn(app, event)
            % fileSelectorInput = app.FileSelector.Value;
            % LicenseInput = app.LicenseDropDown.Value;
            % datasetBranchTitleInput = app.DatasetBranchTitleEditField.Value;
            % abstractInput = app.AbstractTextArea.Value;
            % commentsInput = app.CommentsAdditionalDetailsTextArea.Value;
            ds.fileSelectorInput = app.FileSelector.Value;
            ds.licenseInput = app.LicenseDropDown.Value;
            ds.datasetBranchTitleInput = app.DatasetBranchTitleEditField.Value;
            ds.abstractInput = app.AbstractTextArea.Value;
            ds.commentsInput = app.CommentsAdditionalDetailsTextArea.Value;
            fig.UserData = ds;
            ud = fig.UserData;
            % data = struct('funding', fileSelectorInput, 'License', LicenseInput,'datasetBranchTitle', datasetBranchTitleInput, 'abstract',abstractInput, 'comments', commentsInput);
            ndi.database.fun.dataset_metadata('steps', 1, 'action', 'save', 'data', ud);
        end

        % Button pushed function: NextButton
        function NextButtonPushedFcn(app, event)
            fig = app.UIFigure;
            ds.fileSelectorInput = app.FileSelector.Value;
            ds.licenseInput = app.LicenseDropDown.Value;
            ds.datasetBranchTitleInput = app.DatasetBranchTitleEditField.Value;
            ds.abstractInput = app.AbstractTextArea.Value;
            ds.commentsInput = app.CommentsAdditionalDetailsTextArea.Value;
            set(fig,'UserData',ds);
            ud = fig.UserData;
            ndi.database.fun.dataset_metadata('steps', 2, 'action', 'open');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatasetIdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetIdentifierEditFieldLabel.Text = 'Dataset Identifier';
            app.DatasetIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetIdentifierEditField.InputType = 'alphanumerics';
            app.DatasetIdentifierEditField.Tooltip = {'Auto-generated digital nametag for your dataset, no input required'};
            app.DatasetIdentifierEditField.Value = 'GENERATE';
            app.LicenseDropDownLabel = uilabel(app.UIFigure);
            app.LicenseDropDownLabel.Text = 'License';
            app.LicenseDropDown = uidropdown(app.UIFigure);
            app.LicenseDropDown.Tooltip = {'Choose from one of our approved licenses'};
            app.FileSelector = wt.FileSelector(app.UIFigure);
            app.FileSelector.Tooltip = "Accepted formats: PDF, DOCX, XML";
            app.FundingLabel = uilabel(app.UIFigure);
            app.FundingLabel.Text = 'Funding';
            app.DatasetBranchTitleEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetBranchTitleEditFieldLabel.Text = 'Dataset Branch Title';
            app.DatasetBranchTitleEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetBranchTitleEditField.Tooltip = {'Assign a name to this branch'};
            app.DATASETESSENTIALSLabel = uilabel(app.UIFigure);
            app.DATASETESSENTIALSLabel.Text = 'DATASET ESSENTIALS';
            app.CommentsAdditionalDetailsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsAdditionalDetailsTextAreaLabel.Text = {'Comments/ '; 'Additional Details'};
            app.CommentsAdditionalDetailsTextArea = uitextarea(app.UIFigure);
            app.CommentsAdditionalDetailsTextArea.Tooltip = {'Anything else you''d like us to know about your upload? Note: This field is not public facing.'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            app.AbstractTextAreaLabel = uilabel(app.UIFigure);
            app.AbstractTextAreaLabel.Text = 'Abstract';
            app.AbstractTextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Welcome to the NDI Cloud''s Upload Tool!'; ''; 'We''re excited to have you here. This is your upload form, where you can effortlessly share your data with us. Over the next few pages, we''ll guide you through the process of ingesting your valuable data. Our goal is to make this as seamless as possible, ensuring that your information is accurately processed and ready for analysis. If you ever need assistance with any of the form elements, simply hover over the respective item to access helpful information. For any further queries, shoot us an email at info@walthamdatascience.com and we''ll get right back to you! Thank you for choosing our app to help you manage your data. Let''s get started on this journey together!'};
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushedFcn, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end